classdef Eleni_Component < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        MainMenu1Button             matlab.ui.control.Button
        HyperlinkYellow             matlab.ui.control.Hyperlink
        Yellow_Text_Area            matlab.ui.control.TextArea
        Drought_Image               matlab.ui.control.Image
        WanttolearnabouttheeffectsofdroughtonthehumanbodyButton  matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        GraphofGroundwaterContaminatesinUSButton  matlab.ui.control.Button
        MainMenu2Button             matlab.ui.control.Button
        Hyperlink2_2                matlab.ui.control.Hyperlink
        YellowPanelText             matlab.ui.control.TextArea
        GroundWater_Image           matlab.ui.control.Image
        Groundwater_PolutionButton  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        MainMenu3Button             matlab.ui.control.Button
        Hyperlink3                  matlab.ui.control.Hyperlink
        GreenPanelText              matlab.ui.control.TextArea
        WanttolearnaboutuncleanwaterusedforagricultareffectsusButton_5  matlab.ui.control.Button
        Image3                      matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Clicked1 = 0 % Description
    end
    
    properties (Access = private)
        Clicked2 =0 % Description
        Clicked3 = 0% Description
    end
    
    properties (Access = public)
        MainClicked = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % WanttolearnabouttheeffectsofdroughtonthehumanbodyButton
        function WanttolearnabouttheeffectsofdroughtonthehumanbodyButtonPushed(app, event)
            if app.Clicked1 == 0
                app.Clicked1 = 1;
                app.Drought_Image.Visible =1;
                app.Drought_Image.Enable =1;
                app.Yellow_Text_Area.Visible=1;
                app.Yellow_Text_Area.Enable=1;
                app.HyperlinkYellow.Visible =1;
                app.HyperlinkYellow.Enable =1; 
                app.MainMenu1Button.Visible =1;
                app.MainMenu1Button.Enable =1;
              set(app.Yellow_Text_Area, 'editable','off')
            end
        end

        % Button pushed function: Groundwater_PolutionButton
        function Groundwater_PolutionButtonPushed(app, event)
            if app.Clicked2 ==0
                app.Clicked2 = 1;
                app.GroundWater_Image.Visible =1;
                app.GroundWater_Image.Enable =1;
                 app.YellowPanelText.Visible=1;
                app.YellowPanelText.Enable=1;
                app.Hyperlink2_2.Visible =1;
                app.Hyperlink2_2.Enable =1;
                app.MainMenu2Button.Visible =1;
                app.MainMenu2Button.Enable =1;
                app.GraphofGroundwaterContaminatesinUSButton.Visible =1;
                app.GraphofGroundwaterContaminatesinUSButton.Enable =1;
                
            end
        end

        % Button pushed function: 
        % WanttolearnaboutuncleanwaterusedforagricultareffectsusButton_5
        function WanttolearnaboutuncleanwaterusedforagricultareffectsusButton_5Pushed(app, event)
             if app.Clicked3==0
                 app.Clicked3 = 1;
                 app.Image3.Visible=1;
                app.Image3.Enable =1;
                app.GreenPanelText.Visible=1;
                app.GreenPanelText.Enable=1;
                app.Hyperlink3.Visible =1;
                app.Hyperlink3.Enable =1;
                app.MainMenu3Button.Visible =1;
                app.MainMenu3Button.Enable =1;
               
            end

        end

        % Button pushed function: MainMenu1Button
        function MainMenu1ButtonPushed(app, event)
          h2o
                delete(app)
             
        end

        % Button pushed function: MainMenu2Button
        function MainMenu2ButtonPushed(app, event)
            h2o
                delete(app)
           
        end

        % Button pushed function: MainMenu3Button
        function MainMenu3ButtonPushed(app, event)
             h2o
                delete(app)
              
        end

        % Callback function
        function GraphofDroughtConditionsacrosstheUSButton_2Pushed(app, event)

               DroughtGraph
            
        end

        % Button pushed function: GraphofGroundwaterContaminatesinUSButton
        function GraphofGroundwaterContaminatesinUSButtonPushed(app, event)
            GroundwaterGraph
        end

        % Callback function
        function ChooseAnotherButtonPushed(app, event)
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x', 282};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WanttolearnabouttheeffectsofdroughtonthehumanbodyButton = uibutton(app.LeftPanel, 'push');
            app.WanttolearnabouttheeffectsofdroughtonthehumanbodyButton.ButtonPushedFcn = createCallbackFcn(app, @WanttolearnabouttheeffectsofdroughtonthehumanbodyButtonPushed, true);
            app.WanttolearnabouttheeffectsofdroughtonthehumanbodyButton.Text = 'Want to learn about the effects of drought on the human body? If so, CLICK ME!';
            app.Drought_Image = uiimage(app.LeftPanel);
            app.Drought_Image.Visible = 'off';
            app.Drought_Image.ImageSource = '20200825_usdm_0.png';
            app.Yellow_Text_Area = uitextarea(app.LeftPanel);
            app.Yellow_Text_Area.Editable = 'off';
            app.Yellow_Text_Area.Visible = 'off';
            app.Yellow_Text_Area.Value = {'Drought is a big topic in many areas around the US. Besides the obvious, living in a drought can have some unbenounced consequences to our health. As we all are aware, we have different polutants in our water but during a drought, the concentration on these pollutants are increased therfore increase how much we consume of these substances such as increased heavy metals as well as bacteria in th water. These can cause an upset stomach or fever on the mild side or worse consequences based on what specifically you are consuming.  Below is the link to more information about this'};
            app.HyperlinkYellow = uihyperlink(app.LeftPanel);
            app.HyperlinkYellow.URL = 'https://www.cdc.gov/nceh/drought/implications.htm#anchor_1571768790584';
            app.HyperlinkYellow.Visible = 'off';
            app.HyperlinkYellow.Text = {'Link to more information.'; ''};
            app.MainMenu1Button = uibutton(app.LeftPanel, 'push');
            app.MainMenu1Button.ButtonPushedFcn = createCallbackFcn(app, @MainMenu1ButtonPushed, true);
            app.MainMenu1Button.Visible = 'off';
            app.MainMenu1Button.Text = 'Main Menu';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Groundwater_PolutionButton = uibutton(app.CenterPanel, 'push');
            app.Groundwater_PolutionButton.ButtonPushedFcn = createCallbackFcn(app, @Groundwater_PolutionButtonPushed, true);
            app.Groundwater_PolutionButton.Text = 'Want to learn about the effect of ground water pollution on the human body? If so,  CLICK ME!';
            app.GroundWater_Image = uiimage(app.CenterPanel);
            app.GroundWater_Image.Visible = 'off';
            app.GroundWater_Image.ImageSource = 'groundwater-contamination.jpg';
            app.YellowPanelText = uitextarea(app.CenterPanel);
            app.YellowPanelText.Editable = 'off';
            app.YellowPanelText.Visible = 'off';
            app.YellowPanelText.Value = {'When you turn on the faucet to wash your hands or grab a glass of water, most likely it is being drawn from an underground aquifer. This water can become contaminated under certain conditions with things such as microorganisms, nitrate/nitrite, organic chemicals and heavy metals. Microorganisms such as bacterial can cause  gastrointestinal illnesses and infections. One of my sources says "High levels of nitrate/nitrite in drinking water can cause methemoglobinemia or "blue baby syndrome". These substances reduce the blood''s ability to carry oxygen."Increased levels or organic chemicals as well as heavy metals can effects how the heart, liver as well as kidney functions. '};
            app.Hyperlink2_2 = uihyperlink(app.CenterPanel);
            app.Hyperlink2_2.URL = 'https://www.epa.gov/privatewells/potential-well-water-contaminants-and-their-impacts';
            app.Hyperlink2_2.Visible = 'off';
            app.Hyperlink2_2.Text = 'Link to more information.';
            app.MainMenu2Button = uibutton(app.CenterPanel, 'push');
            app.MainMenu2Button.ButtonPushedFcn = createCallbackFcn(app, @MainMenu2ButtonPushed, true);
            app.MainMenu2Button.Visible = 'off';
            app.MainMenu2Button.Text = 'Main Menu';
            app.GraphofGroundwaterContaminatesinUSButton = uibutton(app.CenterPanel, 'push');
            app.GraphofGroundwaterContaminatesinUSButton.ButtonPushedFcn = createCallbackFcn(app, @GraphofGroundwaterContaminatesinUSButtonPushed, true);
            app.GraphofGroundwaterContaminatesinUSButton.Visible = 'off';
            app.GraphofGroundwaterContaminatesinUSButton.Text = 'Graph of Groundwater Contaminates in US';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image3 = uiimage(app.RightPanel);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = '190918-arizona-water-climate-change-farming-colorado-river-knorr-farms-11-top-4.jpg';
            app.WanttolearnaboutuncleanwaterusedforagricultareffectsusButton_5 = uibutton(app.RightPanel, 'push');
            app.WanttolearnaboutuncleanwaterusedforagricultareffectsusButton_5.ButtonPushedFcn = createCallbackFcn(app, @WanttolearnaboutuncleanwaterusedforagricultareffectsusButton_5Pushed, true);
            app.WanttolearnaboutuncleanwaterusedforagricultareffectsusButton_5.Text = 'Want to learn about unclean water used for agricultar effects us?If so, CLICK ME';
            app.GreenPanelText = uitextarea(app.RightPanel);
            app.GreenPanelText.Editable = 'off';
            app.GreenPanelText.Visible = 'off';
            app.GreenPanelText.Value = {'When you go to the store do you every think about how the produce you are consuming was made or where the water that was used comes from? There are so many different agricultural polutants that can cause serious health problems. The chemical runoff from one farm can then be used for another and this process just contiues downstream. Aside from fertilizers and pesticides that can contaminate the water, animal waste is another huge contributer. Both of these things can hurt the produce that we consume and thier effects can harm us. They can harm the funtion or your vital organs as well as cause digestive issues. '; ''};
            app.Hyperlink3 = uihyperlink(app.RightPanel);
            app.Hyperlink3.URL = 'https://lawreview.vermontlaw.edu/wp-content/uploads/2013/08/14-Laitos-Ruckriegle.pdf';
            app.Hyperlink3.Visible = 'off';
            app.Hyperlink3.Text = 'Link to more information. ';
            app.MainMenu3Button = uibutton(app.RightPanel, 'push');
            app.MainMenu3Button.ButtonPushedFcn = createCallbackFcn(app, @MainMenu3ButtonPushed, true);
            app.MainMenu3Button.Visible = 'off';
            app.MainMenu3Button.Text = 'Main Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eleni_Component

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end