classdef FinalComp_Jose < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Country2Button  matlab.ui.control.Button
        TextArea        matlab.ui.control.TextArea
        Image           matlab.ui.control.Image
        Country1Button  matlab.ui.control.Button
        MainMenuButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes)
        end

        % Button pushed function: Country1Button
        function Country1ButtonPushed(app, event)
            Data = readmatrix("plotData.csv");
            x = Data(1:16,3);      
            y = Data(1:16,4) / (1*10^6);
            plot(app.UIAxes,x,y)
            title(app.UIAxes,'Albania');
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            delete(app)
            h2o
        end

        % Button pushed function: Country2Button
        function Country2ButtonPushed(app, event)
            Data = readmatrix("plotData.csv");
            x2 = Data(17:32,3);
            y2 = Data(17:32,4) / (1*10^6);
            plot(app.UIAxes,x2,y2)
            title(app.UIAxes,'Argentina');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Country')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'People without acess to safe drinking water [million]')
            zlabel(app.UIAxes, 'Z')
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.Country1Button = uibutton(app.UIFigure, 'push');
            app.Country1Button.ButtonPushedFcn = createCallbackFcn(app, @Country1ButtonPushed, true);
            app.Country1Button.Text = 'Country 1';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Distillation.jpg';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Distillation machines have been around for years and are a common way of removing bacteria, inorganic compounds, and other harmful materials. A unit smaller than a dishwasher could provide daily clean water for 100 people. It also takes 4 to 6 hours for a normal sized unit to produce one gallon of distilled water'};
            app.Country2Button = uibutton(app.UIFigure, 'push');
            app.Country2Button.ButtonPushedFcn = createCallbackFcn(app, @Country2ButtonPushed, true);
            app.Country2Button.Text = 'Country 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalComp_Jose

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end