classdef GroundwaterGraph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GraphPeoplewithAccessButton     matlab.ui.control.Button
        GraphPeoplewithoutAccessButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Plot = 0 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Button pushed function: GraphPeoplewithoutAccessButton
        function GraphPeoplewithoutAccessButtonPushed(app, event)

                load("NecessaryData.mat","X2","Y2")
                plot(app.UIAxes,X2,(Y2*10^-7),'MarkerFaceColor','k','LineWidth',3)
            title(app.UIAxes, 'Populations and thier acess to Clean Water US')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Population Numbers [number*10^7]')
            xticks(app.UIAxes,[1991:2002])
            axis(app.UIAxes, [1991 2002 0 .5]);
            
        end

        % Button pushed function: GraphPeoplewithAccessButton
        function GraphPeoplewithAccessButtonPushed(app, event)
            
            load("NecessaryData.mat","X2","Y3")
            
            plot(app.UIAxes, X2,Y3,'MarkerFaceColor','k','LineWidth',3)
            app.UIAxes.GridLineStyle = '-';
            title(app.UIAxes, 'Populations and Their access to clean water US')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes,'Population Numbers')
            xticks(app.UIAxes,[1991:2002])
            axis(app.UIAxes,[1991 2002 0 500000000])


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Populations and thier acess to Clean Water US')
            xlabel(app.UIAxes, 'Years')
            ylabel(app.UIAxes, 'Population Numbers')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 85];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GraphPeoplewithoutAccessButton = uibutton(app.UIFigure, 'push');
            app.GraphPeoplewithoutAccessButton.ButtonPushedFcn = createCallbackFcn(app, @GraphPeoplewithoutAccessButtonPushed, true);
            app.GraphPeoplewithoutAccessButton.Text = 'Graph People without Access';
            app.GraphPeoplewithAccessButton = uibutton(app.UIFigure, 'push');
            app.GraphPeoplewithAccessButton.ButtonPushedFcn = createCallbackFcn(app, @GraphPeoplewithAccessButtonPushed, true);
            app.GraphPeoplewithAccessButton.Text = {'Graph People with Access'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroundwaterGraph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end