classdef Component_Steven < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LegendLabel               matlab.ui.control.Label
        CountrieswithGDPpercapitalessthan30000Label  matlab.ui.control.Label
        RedLabel                  matlab.ui.control.Label
        GreenLabel                matlab.ui.control.Label
        RedLamp                   matlab.ui.control.Lamp
        NoDataLamp                matlab.ui.control.Lamp
        NoDataLampLabel           matlab.ui.control.Label
        GreenLamp                 matlab.ui.control.Lamp
        AccesstoWater70Lamp       matlab.ui.control.Lamp
        AccesstoWater70LampLabel  matlab.ui.control.Label
        WATERRECLAMATIONLabel     matlab.ui.control.Label
        BACKTOMAINMENUButton_2    matlab.ui.control.Button
        TextArea_2                matlab.ui.control.TextArea
        TextArea_5                matlab.ui.control.TextArea
        CountryTable              matlab.ui.control.Table
        UITable                   matlab.ui.control.Table
        INTRODUCTIONButton        matlab.ui.control.StateButton
        FLButton                  matlab.ui.control.Button
        COButton                  matlab.ui.control.Button
        NVButton                  matlab.ui.control.Button
        AZButton                  matlab.ui.control.Button
        TXButton                  matlab.ui.control.Button
        CAButton                  matlab.ui.control.Button
        ClickonastatetoseelistofreclamationfacilitiesLabel  matlab.ui.control.Label
        SOLUTIONButton            matlab.ui.control.StateButton
        DevelopingcountrieswithlessGDPCthanselectedcountyLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
        ContextMenu2              matlab.ui.container.ContextMenu
        Menu_2                    matlab.ui.container.Menu
        Menu2_2                   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Us,map,tr = imread("US MAP.png") % Description
        AZData = readcell("INFO.xlsx","Sheet",1);
        CAData = readcell("INFO.xlsx","Sheet",2);
        COData = readcell("INFO.xlsx","Sheet",3);
        FLData = readcell("INFO.xlsx","Sheet",4);
        NVData = readcell("INFO.xlsx","Sheet",5);
        TXData = readcell("INFO.xlsx","Sheet",6);
        numData = readmatrix("numData.csv");
        Countries = readcell("WorldGDP.xls","Sheet",1);
        GDP = readmatrix("WorldGDP.xls","Sheet",2)
        CPercent = readcell("Percent.xlsx","Sheet",1)
        Percent = readmatrix("Percent.xlsx","Sheet",2)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Value changed function: INTRODUCTIONButton
        function INTRODUCTIONButtonValueChanged(app, event)
            value = app.INTRODUCTIONButton.Value;
            if value == 1
               
                app.TextArea_2.Visible = 1;
            else 
                app.TextArea_2.Visible = 0;
            end
        end

        % Button pushed function: CAButton
        function CAButtonPushed(app, event)
         app.UITable.Data = app.CAData(1:end,1:4);
         app.UITable.Visible = 1;
        end

        % Button pushed function: FLButton
        function FLButtonPushed(app, event)
         app.UITable.Data = app.FLData(1:end,1:4);
          app.UITable.Visible = 1;
        end

        % Button pushed function: COButton
        function COButtonPushed(app, event)
         app.UITable.Data = app.COData(1:end,1:4);
          app.UITable.Visible = 1;
        end

        % Button pushed function: NVButton
        function NVButtonPushed(app, event)
         app.UITable.Data = app.NVData(1:end,1:4);
          app.UITable.Visible = 1;
        end

        % Button pushed function: TXButton
        function TXButtonPushed(app, event)
         app.UITable.Data = app.TXData(1:end,1:4);
          app.UITable.Visible = 1;
        end

        % Button pushed function: AZButton
        function AZButtonPushed(app, event)
         app.UITable.Data = app.AZData(2:end,1:4);
          app.UITable.Visible = 1;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            R = indices(1);
            CompGDP = cell2mat(app.UITable.Data(R,4));
            Mask = (app.GDP(2:end,1) <= CompGDP) & (app.GDP(2:end,1) < 30000);
            MoneyC = find((app.GDP(2:end,1)) <= CompGDP,sum(Mask));
            app.CountryTable.Data = app.Countries(MoneyC+1,1:2);
            app.CountryTable.Visible = 1;
            app.DevelopingcountrieswithlessGDPCthanselectedcountyLabel.Visible = 1;
            app.AccesstoWater70Lamp.Visible = 1;
            app.AccesstoWater70LampLabel.Visible = 1;
            app.GreenLamp.Visible = 1;
            app.GreenLabel.Visible = 1;
            app.RedLabel.Visible = 1;
            app.RedLamp.Visible = 1;
            app.NoDataLamp.Visible = 1;
            app.NoDataLampLabel.Visible = 1;
           app.CountrieswithGDPpercapitalessthan30000Label.Visible = 1;
           app.LegendLabel.Visible=1;


        end

        % Value changed function: SOLUTIONButton
        function SOLUTIONButtonValueChanged(app, event)
            value = app.SOLUTIONButton.Value;
            if value == 1
                app.TextArea_5.Visible = 1;
            else 
                app.TextArea_5.Visible = 0;
            end
        end

        % Button pushed function: BACKTOMAINMENUButton_2
        function BACKTOMAINMENUButton_2Pushed(app, event)
            h2o
            delete(app.UIFigure)
            
            
        end

        % Cell selection callback: CountryTable
        function CountryTableCellSelection(app, event)
            indices = event.Indices;
            R = indices(1);
          
           Compare = find(string(app.CPercent) == string(app.CountryTable.Data(R,1)));
           
           if isempty(Compare) == 1
               app.AccesstoWater70Lamp.Color = [1,1,1];
           
           elseif app.Percent(Compare) < 70
                app.AccesstoWater70Lamp.Color = [1,0,0];
           elseif  app.Percent(Compare) >= 70
               app.AccesstoWater70Lamp.Color = [0,1,0];
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'US MAP.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DevelopingcountrieswithlessGDPCthanselectedcountyLabel = uilabel(app.UIFigure);
            app.DevelopingcountrieswithlessGDPCthanselectedcountyLabel.Visible = 'off';
            app.DevelopingcountrieswithlessGDPCthanselectedcountyLabel.Text = 'Developing* countries with less GDP/C than selected county';
            app.SOLUTIONButton = uibutton(app.UIFigure, 'state');
            app.SOLUTIONButton.ValueChangedFcn = createCallbackFcn(app, @SOLUTIONButtonValueChanged, true);
            app.SOLUTIONButton.Text = 'SOLUTION';
            app.ClickonastatetoseelistofreclamationfacilitiesLabel = uilabel(app.UIFigure);
            app.ClickonastatetoseelistofreclamationfacilitiesLabel.Text = 'Click on a state to see list of reclamation facilities.';
            app.CAButton = uibutton(app.UIFigure, 'push');
            app.CAButton.ButtonPushedFcn = createCallbackFcn(app, @CAButtonPushed, true);
            app.CAButton.Text = 'CA';
            app.TXButton = uibutton(app.UIFigure, 'push');
            app.TXButton.ButtonPushedFcn = createCallbackFcn(app, @TXButtonPushed, true);
            app.TXButton.Text = 'TX';
            app.AZButton = uibutton(app.UIFigure, 'push');
            app.AZButton.ButtonPushedFcn = createCallbackFcn(app, @AZButtonPushed, true);
            app.AZButton.Text = 'AZ';
            app.NVButton = uibutton(app.UIFigure, 'push');
            app.NVButton.ButtonPushedFcn = createCallbackFcn(app, @NVButtonPushed, true);
            app.NVButton.Text = 'NV';
            app.COButton = uibutton(app.UIFigure, 'push');
            app.COButton.ButtonPushedFcn = createCallbackFcn(app, @COButtonPushed, true);
            app.COButton.Text = 'CO';
            app.FLButton = uibutton(app.UIFigure, 'push');
            app.FLButton.ButtonPushedFcn = createCallbackFcn(app, @FLButtonPushed, true);
            app.FLButton.Text = 'FL';
            app.INTRODUCTIONButton = uibutton(app.UIFigure, 'state');
            app.INTRODUCTIONButton.ValueChangedFcn = createCallbackFcn(app, @INTRODUCTIONButtonValueChanged, true);
            app.INTRODUCTIONButton.Text = 'INTRODUCTION';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'City'; 'County'; 'State'; 'GDP per Capita'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.UITable.Visible = 'off';
            app.CountryTable = uitable(app.UIFigure);
            app.CountryTable.ColumnName = {'Country'; 'GDP per Capita'};
            app.CountryTable.RowName = {};
            app.CountryTable.SelectionType = 'row';
            app.CountryTable.CellSelectionCallback = createCallbackFcn(app, @CountryTableCellSelection, true);
            app.CountryTable.Multiselect = 'off';
            app.CountryTable.Visible = 'off';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Visible = 'off';
            app.TextArea_5.Value = {'From the tables, it''s clear that not every country has the same wealth as the various counties listed below. However, there are other ways to recycle water as well. Many of them follow the same principles: filtration using sand, gravel, and microorganisms. In a small village or town, slow sand filters can be used. They are extremely cheap compared to these giant facilities, averaging about $100 per filter. The only downside is that the water is not being as thoroughly cleaned, but it still reduces diahrreal disease cases by 44%. On a smaller scale, there are many other methods of filtering water, from leaving water in the sunlight or just simply running water through sand. While they might not produce clear sping water, it will drastically reduce the deaths from unclean water.'; ''; '(Click ''CONCLUSION'' to close text)'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_2.Value = {'Water reclamation is the process of treating used water for reuse. This allows many places to save water, which may become scarce when a drought hits the area. The process of treating reclaimed water consists of many methods of filtration. First, plastic and grease are removed as they are lighter and float to the surface. Next, microorganisms feed on the organic molecules in the water in aeration tanks in order to clean out the materials that are too small for us to extract. Lastly, the water is run through a multiple-layered filter that strips the water of any suspended particles.'; ''; ' Although water reclamation is a great way to retain and save water, it comes at quite a steep price, making it unaccessible to countries that really need it. On the graph, you wil see the states with water reclamation facilities and how much they cost. Upon using the app, you will see the general idea of how many countries in need can replicate what we have in the US.'; ''; '(Click ''INTRODUCTION'' to close text)'};
            app.BACKTOMAINMENUButton_2 = uibutton(app.UIFigure, 'push');
            app.BACKTOMAINMENUButton_2.ButtonPushedFcn = createCallbackFcn(app, @BACKTOMAINMENUButton_2Pushed, true);
            app.BACKTOMAINMENUButton_2.Text = '<< BACK TO MAIN MENU';
            app.WATERRECLAMATIONLabel = uilabel(app.UIFigure);
            app.WATERRECLAMATIONLabel.Text = 'WATER RECLAMATION';
            app.AccesstoWater70LampLabel = uilabel(app.UIFigure);
            app.AccesstoWater70LampLabel.Visible = 'off';
            app.AccesstoWater70LampLabel.Text = 'Access to Water > 70%';
            app.AccesstoWater70Lamp = uilamp(app.UIFigure);
            app.AccesstoWater70Lamp.Visible = 'off';
            app.GreenLamp = uilamp(app.UIFigure);
            app.GreenLamp.Visible = 'off';
            app.NoDataLampLabel = uilabel(app.UIFigure);
            app.NoDataLampLabel.Visible = 'off';
            app.NoDataLampLabel.Text = 'No Data';
            app.NoDataLamp = uilamp(app.UIFigure);
            app.NoDataLamp.Visible = 'off';
            app.RedLamp = uilamp(app.UIFigure);
            app.RedLamp.Visible = 'off';
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Visible = 'off';
            app.GreenLabel.Text = '> 70%';
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Visible = 'off';
            app.RedLabel.Text = '< 70%';
            app.CountrieswithGDPpercapitalessthan30000Label = uilabel(app.UIFigure);
            app.CountrieswithGDPpercapitalessthan30000Label.Visible = 'off';
            app.CountrieswithGDPpercapitalessthan30000Label.Text = {'*Countries with GDP per capita less than '; '  $30,000 '};
            app.LegendLabel = uilabel(app.UIFigure);
            app.LegendLabel.Visible = 'off';
            app.LegendLabel.Text = 'Legend:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.CountryTable.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.SOLUTIONButton.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Component_Steven

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end