classdef EffectsofUnsafeWater_Junaid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        PLOTButton      matlab.ui.control.Button
        DropDown        matlab.ui.control.DropDown
        EffectofUnsafeWaterontheGlobalPopulationLabel  matlab.ui.control.Label
        MAINMENUButton  matlab.ui.control.Button
        Image           matlab.ui.control.Image
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
disableDefaultInteractivity(app.UIAxes)
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
          
            if (strcmp(app.DropDown.Value,'Global Deaths'))
            t = readmatrix("data.csv");
            x = t(6357:6384,3);
            y = t(6357:6384,4);
            plot(app.UIAxes,x,y);
            xlabel(app.UIAxes,'Year');
            ylabel(app.UIAxes,'# of Deaths');
            title(app.UIAxes,'Global Deaths Caused by Unsafe Water Sources');
            disableDefaultInteractivity(app.UIAxes)
   
            elseif (strcmp(app.DropDown.Value,'Global Death Rate'))
            t = readmatrix("data2.csv");
            x = t(6357:6384,3);
            y = t(6357:6384,4);
            plot(app.UIAxes,x,y);
            xlabel(app.UIAxes,'Year');
            ylabel(app.UIAxes,'Death Rate');
            title(app.UIAxes,'Global Death Rate Caused by Unsafe Water Sources');
            disableDefaultInteractivity(app.UIAxes)
           

            elseif (strcmp(app.DropDown.Value,'Population w/o safe drinking water'))
            t = readmatrix("data3.csv");
            x = t(1496:1511,3);
            y = t(1496:1511,4);
            plot(app.UIAxes,x,y);
            xlabel(app.UIAxes,'Year');
            ylabel(app.UIAxes,'Population');
            title(app.UIAxes,'Population w/o access to safe drinking water');
            disableDefaultInteractivity(app.UIAxes)
              
        elseif (strcmp(app.DropDown.Value,'Population w/ safe drinking water'))
            t = readmatrix("data3.csv");
            x = t(1496:1511,3);
            y = t(1496:1511,5);
            plot(app.UIAxes,x,y);
            xlabel(app.UIAxes,'Year');
            ylabel(app.UIAxes,'Population');
            title(app.UIAxes,'Population w/ access to safe drinking water');
            disableDefaultInteractivity(app.UIAxes)
        end
       
        end

        % Button pushed function: MAINMENUButton
        function MAINMENUButtonPushed(app, event)
            delete(app);
            h2o
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'image.jpg';
            app.MAINMENUButton = uibutton(app.UIFigure, 'push');
            app.MAINMENUButton.ButtonPushedFcn = createCallbackFcn(app, @MAINMENUButtonPushed, true);
            app.MAINMENUButton.Text = {'MAIN MENU'; ''};
            app.EffectofUnsafeWaterontheGlobalPopulationLabel = uilabel(app.UIFigure);
            app.EffectofUnsafeWaterontheGlobalPopulationLabel.Text = 'Effect of Unsafe Water on the Global Population';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Global Deaths', 'Global Death Rate', 'Population w/o safe drinking water', 'Population w/ safe drinking water'};
            app.DropDown.Value = 'Global Deaths';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'  The widespread occurrence of unsafe water sources is exposing '; '  humans to a variety of health risks that can potentially lead to '; '  contracting infectious diseases such as cholera, diarrhea, dysentery, '; '  hepatitis A, typhoid, and polio. Not only are humans vulnerable to '; '  these diseases, but exposure to contaminated water attributes to '; '  disrupting our body''s abilities, damaging our immune and '; '  reproductive systems, altering our brain function, and even causing '; '  death. The severity of this issue ranks as one of the greatest risk '; '  factors for death all around the world. '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EffectsofUnsafeWater_Junaid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end