classdef WaylinComponentFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ROWButton_2     matlab.ui.control.StateButton
        BRICSButton_2   matlab.ui.control.StateButton
        OECDButton_2    matlab.ui.control.StateButton
        TextArea_5      matlab.ui.control.TextArea
        TextArea_4      matlab.ui.control.TextArea
        TextArea_3      matlab.ui.control.TextArea
        Hyperlink       matlab.ui.control.Hyperlink
        MainMenuButton  matlab.ui.control.Button
        PlotButton      matlab.ui.control.Button
        DropDown        matlab.ui.control.DropDown
        Image           matlab.ui.control.Image
        TextArea        matlab.ui.control.TextArea
        FreshWaterUseStressandItsScaryEffectsLabel  matlab.ui.control.Label
        KeyLabel        matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
disableDefaultInteractivity(app.UIAxes)

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Data = readmatrix("global-freshwater-use-over-the-long-run.csv");
            %x = Data(1:16,3);      
            %y = Data(1:16,4) / (1*10^6);
            %plot(app.UIAxes,x,y)

            if (strcmp(app.DropDown.Value,'Global Freshwater Use'))
            t = xlsread('global-freshwater-use-over-the-long-run.xls','World');
            x = t(1:111,1); %(6357:6384,3);
            y = t(1:111,2); %(6357:6384,4);
            plot(app.UIAxes,x,y);
            area(app.UIAxes,x,y);
            area(app.UIAxes,x,y,'FaceColor',[0.93,0.69,0.13]);
            xlim(app.UIAxes,[1901 2014])
            xlabel(app.UIAxes,'Year');
            ylabel(app.UIAxes,'Cubic Metres (m³)');
            title(app.UIAxes,'Global freshwater withdrawals since 1900');
            disableDefaultInteractivity(app.UIAxes)

            elseif (strcmp(app.DropDown.Value,'OECD'))
            t = xlsread('global-freshwater-use-over-the-long-run.xls','OECD');
            x = t(1:110,1); %(6357:6384,3);
            y = t(1:110,2); %(6357:6384,4);
            plot(app.UIAxes,x,y);
            area(app.UIAxes,x,y,'FaceColor',[0.93,0.69,0.13]);
            xlim(app.UIAxes,[1901 2010])
            xlabel(app.UIAxes,'Year');
            ylabel(app.UIAxes,'Cubic Metres (m³)');
            title(app.UIAxes,'Global freshwater withdrawals since 1900');
            disableDefaultInteractivity(app.UIAxes)

            elseif (strcmp(app.DropDown.Value,'BRICS'))
            t = xlsread('global-freshwater-use-over-the-long-run.xls','BRICS');
            x = t(1:110,1); %(6357:6384,3);
            y = t(1:110,2); %(6357:6384,4);
            plot(app.UIAxes,x,y);
            area(app.UIAxes,x,y);
            area(app.UIAxes,x,y,'FaceColor',[0.93,0.69,0.13]);
            xlim(app.UIAxes,[1901 2010])
            xlabel(app.UIAxes,'Year');
            ylabel(app.UIAxes,'Cubic Metres (m³)');
            title(app.UIAxes,'Global freshwater withdrawals since 1900');
            disableDefaultInteractivity(app.UIAxes)

            elseif (strcmp(app.DropDown.Value,'ROW'))
            t = xlsread('global-freshwater-use-over-the-long-run.xls','ROW');
            x = t(1:110,1); %(6357:6384,3);
            y = t(1:110,2); %(6357:6384,4);
            plot(app.UIAxes,x,y);
            area(app.UIAxes,x,y);
            area(app.UIAxes,x,y,'FaceColor',[0.93,0.69,0.13]);
            xlim(app.UIAxes,[1901 2010])
            xlabel(app.UIAxes,'Year');
            ylabel(app.UIAxes,'Cubic Metres (m³)');
            title(app.UIAxes,'Global freshwater withdrawals since 1900');
            disableDefaultInteractivity(app.UIAxes)

            %elseif (strcmp(app.DropDown.Value,'Renewable Freshwater Resources'))
            %t = readmatrix("renewable-water-resources-per-capita.csv");
            %x = t(1:441,3); %(6357:6384,3);
            %y = t(1:441,4); %(6357:6384,4);
            %plot(app.UIAxes,x,y);
            %xlabel(app.UIAxes,'Year');
            %ylabel(app.UIAxes,'Cubic Metres (m³)');
            %title(app.UIAxes,'Renewable Freshwater Resources Per Capita');
            %disableDefaultInteractivity(app.UIAxes)

            end
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            delete(app)
            h2o
        end

        % Value changed function: OECDButton_2
        function OECDButton_2ValueChanged(app, event)
            value = app.OECDButton_2.Value;
            if value == 1
               
                app.TextArea_3.Visible = 1;
            else 
                app.TextArea_3.Visible = 0;
            end
            
        end

        % Value changed function: BRICSButton_2
        function BRICSButton_2ValueChanged(app, event)
            value = app.BRICSButton_2.Value;
            if value == 1
                app.TextArea_4.Visible = 1;
            else 
                app.TextArea_4.Visible = 0;
            end
        end

        % Value changed function: ROWButton_2
        function ROWButton_2ValueChanged(app, event)
            value = app.ROWButton_2.Value;
            if value == 1
                app.TextArea_5.Visible = 1;
            else 
                app.TextArea_5.Visible = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.KeyLabel = uilabel(app.UIFigure);
            app.KeyLabel.Text = 'Key';
            app.FreshWaterUseStressandItsScaryEffectsLabel = uilabel(app.UIFigure);
            app.FreshWaterUseStressandItsScaryEffectsLabel.Text = 'FreshWater Use, Stress, and Its Scary Effects ';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'  A growing global population and economic shift towards more resource-intensive consumption patterns means global freshwater use — that is, freshwater withdrawals for agriculture, industry and municipal uses — has increased nearly six-fold since 1900. This is shown in the chart.  Rates of global freshwater use increased sharply from the 1950s onwards, but since 2000 appears to be plateauing, or at least slowing.    '; '  Global freshwater use since 1900 is disaggregated by broad regional groupings. Although absolute freshwater use has growth over this period, the distribution of uses between these regional groupings have not changed significantly over the last century; OECD nations use approximately 20-25 percent; BRICS countries use the largest share at approximately 45 percent; and ROW at 30-33 percent.'};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
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'FreshWaterImage.jpeg';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Global Freshwater Use', 'OECD', 'BRICS', 'ROW'};
            app.DropDown.Value = 'Global Freshwater Use';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot ';
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://ourworldindata.org/water-use-stress#global-freshwater-use';
            app.Hyperlink.Text = 'Click To Learn More';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Visible = 'off';
            app.TextArea_3.Value = {'The Paris-based Organization for Economic Co-operation and Development (OECD) is an international organization that promotes policies to improve the economic and social well-being of people worldwide. It provides a forum in which governments can work together to share experiences and seek solutions to the economic, social and governance challenges they face.'; 'The OECD’s 38 members are: Austria, Australia, Belgium, Canada, Chile, Colombia, Costa Rica, Czech Republic, Denmark, Estonia, Finland, France, Germany, Greece, Hungary, Iceland, Ireland, Israel, Italy, Japan, Korea, Latvia, Lithuania, Luxembourg, Mexico, the Netherlands, New Zealand, Norway, Poland, Portugal, Slovak Republic, Slovenia, Spain, Sweden, Switzerland, Turkey, the United Kingdom and the United States.'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Visible = 'off';
            app.TextArea_4.Value = {'BRICS is the acronym coined to associate five major emerging economies: Brazil, Russia, India, China, and South Africa. The BRICS members are known for their significant influence on regional affairs.'};
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Visible = 'off';
            app.TextArea_5.Value = {'Rest of the World'};
            app.OECDButton_2 = uibutton(app.UIFigure, 'state');
            app.OECDButton_2.ValueChangedFcn = createCallbackFcn(app, @OECDButton_2ValueChanged, true);
            app.OECDButton_2.Text = 'OECD';
            app.BRICSButton_2 = uibutton(app.UIFigure, 'state');
            app.BRICSButton_2.ValueChangedFcn = createCallbackFcn(app, @BRICSButton_2ValueChanged, true);
            app.BRICSButton_2.Text = 'BRICS';
            app.ROWButton_2 = uibutton(app.UIFigure, 'state');
            app.ROWButton_2.ValueChangedFcn = createCallbackFcn(app, @ROWButton_2ValueChanged, true);
            app.ROWButton_2.Text = 'ROW';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaylinComponentFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end