classdef h2o < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EffectsofWaterQualityAccessonHumanHealthLabel  matlab.ui.control.Label
        ReclamationFacilitiesButton  matlab.ui.control.Button
        EffectsofLackofAccesstoCleanWaterButton  matlab.ui.control.Button
        FreshWaterUseStressanditsScaryEffectsButton  matlab.ui.control.Button
        EffectofUnsafeWaterontheGlobalPopulationButton  matlab.ui.control.Button
        DistillationMachinesButton   matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        OceanImage = imread("Ocean.jpg") % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource = app.OceanImage;
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
      
        end

        % Button pushed function: ReclamationFacilitiesButton
        function ReclamationFacilitiesButtonPushed(app, event)
            app.UIFigure.Visible = 0;
            Component_Steven
        end

        % Button pushed function: 
        % EffectofUnsafeWaterontheGlobalPopulationButton
        function EffectofUnsafeWaterontheGlobalPopulationButtonPushed(app, event)
            app.UIFigure.Visible = 0;
            EffectsofUnsafeWater_Junaid
        end

        % Button pushed function: DistillationMachinesButton
        function DistillationMachinesButtonPushed(app, event)
            app.UIFigure.Visible = 0;
            FinalComp_Jose
        end

        % Button pushed function: EffectsofLackofAccesstoCleanWaterButton
        function EffectsofLackofAccesstoCleanWaterButtonPushed(app, event)
            app.UIFigure.Visible = 0;
            Eleni_Component
        end

        % Button pushed function: 
        % FreshWaterUseStressanditsScaryEffectsButton
        function FreshWaterUseStressanditsScaryEffectsButtonPushed(app, event)
            app.UIFigure.Visible = 0;
            WaylinComponentFinal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Ocean.jpg';
            app.TextArea = uitextarea(app.UIFigure);
            app.DistillationMachinesButton = uibutton(app.UIFigure, 'push');
            app.DistillationMachinesButton.ButtonPushedFcn = createCallbackFcn(app, @DistillationMachinesButtonPushed, true);
            app.DistillationMachinesButton.Text = 'Distillation Machines';
            app.EffectofUnsafeWaterontheGlobalPopulationButton = uibutton(app.UIFigure, 'push');
            app.EffectofUnsafeWaterontheGlobalPopulationButton.ButtonPushedFcn = createCallbackFcn(app, @EffectofUnsafeWaterontheGlobalPopulationButtonPushed, true);
            app.EffectofUnsafeWaterontheGlobalPopulationButton.Text = 'Effect of Unsafe Water on the Global Population';
            app.FreshWaterUseStressanditsScaryEffectsButton = uibutton(app.UIFigure, 'push');
            app.FreshWaterUseStressanditsScaryEffectsButton.ButtonPushedFcn = createCallbackFcn(app, @FreshWaterUseStressanditsScaryEffectsButtonPushed, true);
            app.FreshWaterUseStressanditsScaryEffectsButton.Text = 'FreshWater Use, Stress, and its Scary Effects';
            app.EffectsofLackofAccesstoCleanWaterButton = uibutton(app.UIFigure, 'push');
            app.EffectsofLackofAccesstoCleanWaterButton.ButtonPushedFcn = createCallbackFcn(app, @EffectsofLackofAccesstoCleanWaterButtonPushed, true);
            app.EffectsofLackofAccesstoCleanWaterButton.Text = 'Effects of Lack of Access to Clean Water';
            app.ReclamationFacilitiesButton = uibutton(app.UIFigure, 'push');
            app.ReclamationFacilitiesButton.ButtonPushedFcn = createCallbackFcn(app, @ReclamationFacilitiesButtonPushed, true);
            app.ReclamationFacilitiesButton.Text = 'Reclamation Facilities';
            app.EffectsofWaterQualityAccessonHumanHealthLabel = uilabel(app.UIFigure);
            app.EffectsofWaterQualityAccessonHumanHealthLabel.Text = 'Effects of Water Quality/Access on Human Health';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = h2o

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end